classdef clock_project_by_vinayak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        KnowYourTIMEPanel  matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        IXLabel            matlab.ui.control.Label
        XIILabel           matlab.ui.control.Label
        IIILabel           matlab.ui.control.Label
        VILabel            matlab.ui.control.Label
        ListBoxLabel       matlab.ui.control.Label
        ListBox            matlab.ui.control.ListBox
        ILabel             matlab.ui.control.Label
        IILabel            matlab.ui.control.Label
        IVLabel            matlab.ui.control.Label
        VLabel             matlab.ui.control.Label
        VIILabel           matlab.ui.control.Label
        VIIILabel          matlab.ui.control.Label
        XLabel             matlab.ui.control.Label
        XILabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        h_len = 0.6 % Hour hand length
        m_len = 0.8 % Minute hand length
        s_len = 0.9 % Second hand length
        h_color = [0.0 0.0 0.7] % Hour hand color
        m_color = [0.0 0.0 0.5] % Minute hand color
        s_color = [0.0862 0.3137 0.1568] % Second hand color
        clock_on = true % Sets clock on and off
        h % current hour value on clock
        m % current minute value on clock
        s % current second value on clock
    end
    
    methods (Access = private)
        
        function draw_clock_boundary(app)
            r = 1;
            theta = linspace(0, 2*pi, 10000);
            x = r*cos(theta);
            y = r*sin(theta);
            plot(app.UIAxes, x, y, "Color", [20/255 167/255 224/255]);
        end
        
        function draw_clock(app)
            % drawing hour hands
            while true
                draw_clock_boundary(app);
                hold(app.UIAxes, 'on');
                draw_hour_hand(app, app.h + app.m/60);
                draw_minute_hand(app, app.m + app.s/60);
                draw_second_hand(app, app.s);
                hold(app.UIAxes, 'off');
                app.s = mod(app.s+1, 60);
                if app.s==0
                    app.m = mod(app.m+1, 60);
                    if app.m==0
                        app.h = mod(app.h+1, 60);
                    end
                end
                pause(1);
            end
        end
        
        function draw_hour_hand(app, h)
            if (h > 12)
                h = h-12;
            end
            angle = h*-1*360/12 + 90;
            % hold(app.UIAxes, 'on');
            plot(app.UIAxes, [0 app.h_len*cosd(angle)], [0 app.h_len*sind(angle)], "Color", app.h_color, "LineWidth", 5);
            % hold(app.UIAxes, 'off');
        end
        
        function draw_minute_hand(app, m)
            angle = m*-1*360/60 + 90;
            % hold(app.UIAxes, 'on');
            plot(app.UIAxes, [0 app.m_len*cosd(angle)], [0 app.m_len*sind(angle)], "Color", app.m_color, "LineWidth", 3);
            % hold(app.UIAxes, 'off');
        end
        
        function draw_second_hand(app, s)
            angle = s*-1*360/60 + 90;
            % hold(app.UIAxes, 'on');
            plot(app.UIAxes, [0 app.s_len*cosd(angle)], [0 app.s_len*sind(angle)], "Color", app.s_color);
            % hold(app.UIAxes, 'off');
        end
       
        
        function set_clock(app)
            curr_time = datetime('now', 'TimeZone', '-04:00');
            app.h = hour(curr_time);
            app.m = minute(curr_time);
            app.s = second(curr_time);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set_clock(app);
            draw_clock(app);
        end

        % Callback function
        function StartClockButtonValueChanged(app, event)
          
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            if (value == "Natick, MA USA")
                curr_time = datetime('now', 'TimeZone', '-04:00');
            elseif (value == "Galway, Ireland")
                curr_time = datetime('now', 'TimeZone', '+01:00');
            elseif (value == "Hyderabad, India")
                curr_time = datetime('now', 'TimeZone', '+05:30');
            elseif (value == "Beijing, China")
                curr_time = datetime('now', 'TimeZone', '+08:00');
            elseif (value == "Chatswood, Australia")
                curr_time = datetime('now', 'TimeZone', '+10:00');
            end
            app.h = hour(curr_time);
            app.m = minute(curr_time);
            app.s = second(curr_time);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KnowYourTIMEPanel = uipanel(app.UIFigure);
            app.KnowYourTIMEPanel.Title = 'Know Your TIME';
            app.UIAxes = uiaxes(app.KnowYourTIMEPanel);
            title(app.UIAxes, 'CLOCK')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [-1.1 1.1];
            app.UIAxes.YLim = [-1.1 1.1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [];
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [];
            app.IXLabel = uilabel(app.KnowYourTIMEPanel);
            app.IXLabel.Text = 'IX';
            app.XIILabel = uilabel(app.KnowYourTIMEPanel);
            app.XIILabel.Text = 'XII';
            app.IIILabel = uilabel(app.KnowYourTIMEPanel);
            app.IIILabel.Text = 'III';
            app.VILabel = uilabel(app.KnowYourTIMEPanel);
            app.VILabel.Text = 'VI';
            app.ListBoxLabel = uilabel(app.KnowYourTIMEPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.KnowYourTIMEPanel);
            app.ListBox.Items = {'Natick, MA USA', 'Galway, Ireland', 'Hyderabad, India', 'Beijing, China', 'Chatswood, Australia'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Natick, MA USA';
            app.ILabel = uilabel(app.KnowYourTIMEPanel);
            app.ILabel.Text = 'I';
            app.IILabel = uilabel(app.KnowYourTIMEPanel);
            app.IILabel.Text = 'II';
            app.IVLabel = uilabel(app.KnowYourTIMEPanel);
            app.IVLabel.Text = 'IV';
            app.VLabel = uilabel(app.KnowYourTIMEPanel);
            app.VLabel.Text = 'V';
            app.VIILabel = uilabel(app.KnowYourTIMEPanel);
            app.VIILabel.Text = 'VII';
            app.VIIILabel = uilabel(app.KnowYourTIMEPanel);
            app.VIIILabel.Text = 'VIII';
            app.XLabel = uilabel(app.KnowYourTIMEPanel);
            app.XLabel.Text = 'X';
            app.XILabel = uilabel(app.KnowYourTIMEPanel);
            app.XILabel.Text = 'XI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clock_project_by_vinayak

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end